classdef vcop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Button           matlab.ui.control.Button
        Button_2         matlab.ui.control.Button
        Button_3         matlab.ui.control.Button
        Button_4         matlab.ui.control.Button
        Button_5         matlab.ui.control.Button
        Button_6         matlab.ui.control.Button
        Button_7         matlab.ui.control.Button
        Button_8         matlab.ui.control.Button
        Button_9         matlab.ui.control.Button
        Label            matlab.ui.control.Label
        Label_2          matlab.ui.control.Label
        StartButton      matlab.ui.control.Button
        VirtualCopLabel  matlab.ui.control.Label
        TimerLabel       matlab.ui.control.Label
        ScoreLabel       matlab.ui.control.Label
        BustthethievestobringthecitytopeaceLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            t=timer;
            t.Period=1;
            t.ExecutionMode='fixedRate';
            t.TasksToExecute=60;
            t.TimerFcn=@(~,thisEvent) toggleButton(app);
            start(t);
        end

        % Button pushed function: Button, Button_2, Button_5, 
        % Button_7, Button_9
        function ButtonPushed(app, event)
            app.Label.Text=num2str(str2double(app.Label.Text)+50);
        end

        % Button pushed function: Button_3, Button_4, Button_6, 
        % Button_8
        function Button_3Pushed(app, event)
            app.Label.Text=num2str(str2double(app.Label.Text)-25);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 't.jpeg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_2.Icon = 't.jpeg';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'p.jpeg';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_4.Icon = 'p.jpeg';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_5.Icon = 't.jpeg';
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_6.Icon = 'p.jpeg';
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_7.Icon = 't.jpeg';
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_8.Icon = 'p.jpeg';
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_9.Icon = 't.jpeg';
            app.Button_9.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '60';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.VirtualCopLabel = uilabel(app.UIFigure);
            app.VirtualCopLabel.Text = 'Virtual Cop';
            app.TimerLabel = uilabel(app.UIFigure);
            app.TimerLabel.Text = 'Timer';
            app.ScoreLabel = uilabel(app.UIFigure);
            app.ScoreLabel.Text = 'Score';
            app.BustthethievestobringthecitytopeaceLabel = uilabel(app.UIFigure);
            app.BustthethievestobringthecitytopeaceLabel.Text = 'Bust the thieves to bring the city to peace..';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vcop

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end